classdef FibFib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FibFibUIFigure                  matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        toLabel                         matlab.ui.control.Label
        fromLabel                       matlab.ui.control.Label
        YAxisAuto                       matlab.ui.control.CheckBox
        YAxisBot                        matlab.ui.control.Spinner
        YAxisTop                        matlab.ui.control.Spinner
        yaxisRangeLabel                 matlab.ui.control.Label
        FunctionsPanel                  matlab.ui.container.Panel
        PrintAUCmeanFFtableLabel        matlab.ui.control.Label
        DrawalldataintoonegraphLabel    matlab.ui.control.Label
        DraweachtanksdataLabel          matlab.ui.control.Label
        DrawgraphsButton                matlab.ui.control.Button
        DrawmeangraphButton             matlab.ui.control.Button
        PrintAUCmeanFFButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DrawgraphsTab                   matlab.ui.container.Tab
        SaveFigurePathSelectButton      matlab.ui.control.Button
        SaveFigurePath                  matlab.ui.control.EditField
        SavePathEditFieldLabel          matlab.ui.control.Label
        SaveFigureCheckBox              matlab.ui.control.CheckBox
        TrialsPerGraph                  matlab.ui.control.Spinner
        trialspergraphExtinctionDataOnlyLabel  matlab.ui.control.Label
        DrawmeangraphTab                matlab.ui.container.Tab
        GroupNameEditField              matlab.ui.control.EditField
        GroupNameEditFieldLabel         matlab.ui.control.Label
        HoldPrevGraphCheckBox           matlab.ui.control.CheckBox
        PrintAUCmeanFFTab               matlab.ui.container.Tab
        TrialrangeLabel                 matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        AUCTrialRange_2                 matlab.ui.control.Spinner
        AUCTrialRange_1                 matlab.ui.control.Spinner
        sfromCSOnLabel                  matlab.ui.control.Label
        AUCTimeWindow2                  matlab.ui.control.NumericEditField
        sLabel_6                        matlab.ui.control.Label
        AUCTimeWindow1                  matlab.ui.control.NumericEditField
        CalculateAUCAreaUnerCurveandmeanFFduringLabel  matlab.ui.control.Label
        ArtifactRejectionPanel          matlab.ui.container.Panel
        polyfitdetrendLabel             matlab.ui.control.Label
        DetrendEnable                   matlab.ui.control.DropDown
        sLabel_5                        matlab.ui.control.Label
        ArtifactRejectionSeconds        matlab.ui.control.Spinner
        IgnoreinitialLabel_3            matlab.ui.control.Label
        FilterPanel_2                   matlab.ui.container.Panel
        FilterStrength                  matlab.ui.control.Spinner
        smovingaveragefilterLabel       matlab.ui.control.Label
        FilterEnable                    matlab.ui.control.DropDown
        TimewindowPanel                 matlab.ui.container.Panel
        DuringconditiongUSispresentedLabel  matlab.ui.control.Label
        USOffset                        matlab.ui.control.NumericEditField
        sbeforeCSendsLabel              matlab.ui.control.Label
        TimeWindow2                     matlab.ui.control.NumericEditField
        saftertheCSLabel                matlab.ui.control.Label
        sbeforeandLabel                 matlab.ui.control.Label
        TimeWindow1                     matlab.ui.control.NumericEditField
        UsedataduringLabel              matlab.ui.control.Label
        BaselineCorrectionPanel         matlab.ui.container.Panel
        DropdownCorrectStd              matlab.ui.control.DropDown
        baselineusingstdactivityLabel   matlab.ui.control.Label
        DropdownStdMethod               matlab.ui.control.DropDown
        IgnoreinitialLabel_2            matlab.ui.control.Label
        BaselineStdIgnore               matlab.ui.control.Spinner
        sanduseLabel_2                  matlab.ui.control.Label
        BaselineStdUse                  matlab.ui.control.Spinner
        sdataLabel_2                    matlab.ui.control.Label
        sdataLabel                      matlab.ui.control.Label
        BaselineMeanUse                 matlab.ui.control.Spinner
        sanduseLabel                    matlab.ui.control.Label
        BaselineMeanIgnore              matlab.ui.control.Spinner
        IgnoreinitialLabel              matlab.ui.control.Label
        DropdownCorrectMean             matlab.ui.control.DropDown
        baselineusingmeanactivityLabel  matlab.ui.control.Label
        DropdownMeanMethod              matlab.ui.control.DropDown
        TanksPanel                      matlab.ui.container.Panel
        AddTanksButton                  matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        AddTankButton                   matlab.ui.control.Button
        TankList                        matlab.ui.control.ListBox
        ListBoxLabel                    matlab.ui.control.Label
        BasePath                        matlab.ui.control.EditField
        BasePathEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        fs = 1.017252624511719e+03;
        lineObjectForMeanGraphFunction = [];
        lineObjectNameForMeanGraphFunction = [];
    end
    
    methods (Access = private)
        
        function options = readProcessOptions(app)
            options.verbose = false;
            options.timewindow = [-app.TimeWindow1.Value, app.TimeWindow2.Value];
            if strcmp(app.DropdownCorrectMean.Value, "Correct")
                if strcmp(app.DropdownCorrectStd.Value, "Correct")
                    options.baseline_correction = "z";
                else
                    options.baseline_correction = "zero";
                end
            else
                options.baseline_correction = "none";
            end
            if strcmp(app.DropdownMeanMethod.Value, "from every Timewindow.")
                if strcmp(app.DropdownStdMethod.Value, "from every Timewindow.")
                    options.baseline_mode = "trial";
                else
                    options.baseline_mode = "mix";
                end
            else
                options.baseline_mode = "whole";
            end
            options.baseline_trial_duration = app.BaselineMeanUse.Value;
            options.baseline_trial_ignore_duration = app.BaselineMeanIgnore.Value;
            options.baseline_whole_duration = app.BaselineMeanUse.Value;
            options.baseline_whole_ignore_duration = app.BaselineMeanIgnore.Value;
            options.baseline_mix_duration = [app.BaselineMeanUse.Value, app.BaselineStdUse.Value];
            options.baseline_mix_ignore_duration = [app.BaselineMeanIgnore.Value, app.BaselineStdIgnore.Value];
            options.filter = app.FilterStrength.Value;
            if strcmp(app.DetrendEnable.Value, "Do not use")
                options.detrend = false;
            else
                options.detrend = true;
            end
            options.initial_artifact_remove_time = app.ArtifactRejectionSeconds.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Debug Option
            if getenv('username') == "Knowblesse"
                app.BasePath.Value = "D:\Data_fib\fpm220920\Test";
                app.TankList.Items = [...
                    "D:\Data_fib\fpm220920\Test\ToneFib-220920-100431_fpm8_con",...
                    "D:\Data_fib\fpm220920\Test\ToneFib-220920-102302_fpm9_con",...
                    "D:\Data_fib\fpm220920\Test\ToneFib-220920-104339_fpm15_con",...
                    "D:\Data_fib\fpm220920\Test\ToneFib-220920-110922_fpm10_con"];
            end

        end

        % Value changed function: DropdownCorrectMean
        function DropdownCorrectMeanValueChanged(app, event)
            value = app.DropdownCorrectMean.Value;
            if strcmp(value, "Correct")
                app.DropdownCorrectStd.Enable = "on";
            else
                app.DropdownCorrectStd.Enable = "off";
                app.DropdownCorrectStd.Value = "Do not correct";
            end
        end

        % Value changed function: DropdownMeanMethod
        function DropdownMeanMethodValueChanged(app, event)
            if strcmp(app.DropdownMeanMethod.Value, "from every Timewindow.")
                app.BaselineMeanIgnore.Value = 0;
                app.BaselineMeanUse.Value = 1;
                % Setup Std conditions
                app.DropdownStdMethod.Enable = "on";
                app.BaselineStdIgnore.Enable = "on";
                app.BaselineStdUse.Enable = "on";
            else
                app.BaselineMeanIgnore.Value = 60;
                app.BaselineMeanUse.Value = 60;
                % force std method to "whole"
                app.DropdownStdMethod.Enable = "off";
                app.DropdownStdMethod.Value = "at the beginning of the experiment.";
                app.BaselineStdIgnore.Value = app.BaselineMeanIgnore.Value;
                app.BaselineStdUse.Value = app.BaselineMeanUse.Value;
                app.BaselineStdIgnore.Enable = "off";
                app.BaselineStdUse.Enable = "off";
            end               
        end

        % Value changed function: BaselineMeanIgnore, BaselineMeanUse
        function BaselineMeanIgnoreValueChanged(app, event)
            if strcmp(app.DropdownMeanMethod.Value, app.DropdownStdMethod.Value)
                app.BaselineStdIgnore.Value = app.BaselineMeanIgnore.Value;
                app.BaselineStdUse.Value = app.BaselineMeanUse.Value;
            end
        end

        % Value changed function: DropdownStdMethod
        function DropdownStdMethodValueChanged(app, event)
            if strcmp(app.DropdownStdMethod.Value, "from every Timewindow.")
                app.BaselineStdIgnore.Value = app.BaselineMeanIgnore.Value;
                app.BaselineStdUse.Value = app.BaselineMeanUse.Value;
                app.BaselineStdIgnore.Enable = "off";
                app.BaselineStdUse.Enable = "off";
            else
                app.BaselineStdIgnore.Value = 60;
                app.BaselineStdUse.Value = 60;
                app.BaselineStdIgnore.Enable = "on";
                app.BaselineStdUse.Enable = "on";
            end            
        end

        % Value changed function: FilterEnable
        function FilterEnableValueChanged(app, event)
            if strcmp(app.FilterEnable.Value, "Use")
                app.FilterStrength.Enable = "on";
                app.FilterStrength.Value = 0.5;
            else
                app.FilterStrength.Enable = "off";
                app.FilterStrength.Value = 0;
            end
        end

        % Value changed function: FilterStrength
        function FilterStrengthValueChanged(app, event)
            if app.FilterStrength.Value == 0
                app.FilterEnable.Value = "Do not use";
                app.FilterStrength.Enable = "off";
            end
            
        end

        % Button pushed function: AddTankButton
        function AddTankButtonPushed(app, event)
            selpath = uigetdir(app.BasePath.Value, 'Select Tank');
            if selpath ~= 0 
                app.TankList.Items = [app.TankList.Items, selpath];
            end
        end

        % Button pushed function: AddTanksButton
        function AddTanksButtonPushed(app, event)
            selpath = uigetdir(app.BasePath.Value, 'Select Tank Parent Folder');
            if selpath ~= 0 
                list = dir(selpath);
                Tanks = {list([list.isdir]).name};
                Tanks = Tanks(3:end);
                for idx = 1 : numel(Tanks)
                    app.TankList.Items = [app.TankList.Items, ...
                        fullfile(selpath, Tanks(idx))];
                end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if ~isempty(app.TankList.Value)
                app.TankList.Items(cell2mat(cellfun(@(e) strcmp(e, app.TankList.Value), app.TankList.Items, 'UniformOutput', false))) = [];
                app.TankList.Value = {};
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.TankList.Items = {};
            app.TankList.Value = {};
        end

        % Button pushed function: DrawgraphsButton
        function DrawgraphsButtonPushed(app, event)
            for items = app.TankList.Items
                % Parse Tank Name
                result_ = regexp(cell2mat(items), '.*\\(?<tankname>.*)', 'names');
                tankName = result_.tankname;
                exp_type = autoDetectExpType(tankName);

                options = readProcessOptions(app);
                options = namedargs2cell(options);

                Data = loadFibData(cell2mat(items));

                Data = processFibData(Data, options{:});
                
                drawdFF(Data, exp_type, app.TrialsPerGraph.Value, app.USOffset.Value, 'figureName', tankName);

                if app.YAxisAuto.Value == false
                    fig = gcf;
                    for ax = fig.Children
                        ylim(ax, [app.YAxisBot.Value, app.YAxisTop.Value]);
                    end
                end
                % Save Figure if checkbox is true
                if app.SaveFigureCheckBox.Value
                    saveas(gcf, fullfile(app.SaveFigurePath.Value, strcat(tankName, '.png')));
                end
            end
        end

        % Button pushed function: DrawmeangraphButton
        function DrawmeangraphButtonPushed(app, event)
            % TODO Check Tank sanity.
            
            % Find if a figure named "All Figure", 
            % if exist, select it.
            % if not, make one.
            targetFigureObj = [];
            figureObjects_ = findobj;
            for i = 1 : numel(figureObjects_)
                if class(figureObjects_(i)) == "matlab.ui.Figure"
                    if figureObjects_(i).Name == "All Figure"
                        targetFigureObj = figureObjects_(i);
                        break;
                    end
                end
            end
            if isempty(targetFigureObj)
                targetFigureObj = figure('Name', "All Figure");
                app.lineObjectForMeanGraphFunction = []; % init. lost line objects
                app.lineObjectNameForMeanGraphFunction = [];
            end
            
            % If not hold, clean up
            if ~app.HoldPrevGraphCheckBox.Value
                app.lineObjectForMeanGraphFunction = [];
                app.lineObjectNameForMeanGraphFunction = [];
                clf(targetFigureObj);
            end
            
            % Load All data
            numTanks = numel(app.TankList.Items);
            Data = cell(1, numTanks);
            for i = 1 : numTanks
                Data{i} = loadFibData(app.TankList.Items{i}, 'verbose', false);
            end

            % Check if all selected data is the same number of CS and same experiemtn type
            expType = autoDetectExpType(app.TankList.Items{1}, false);
            if numTanks >= 2
                numTrial = Data{1}.numTrial;
                for i = 2 : numTanks
                    if Data{i}.numTrial ~= numTrial
                        errordlg('Tanks with different trial number can not be drawn together', app.FibFibUIFigure.Name);
                        return;
                    end
                    if ~strcmp(expType, autoDetectExpType(app.TankList.Items{i}, false))
                        errordlg('Tanks with different experiment type can not be drawn together', app.FibFibUIFigure.Name);
                        return;
                    end
                end
            end

            % Process the Data
            options = readProcessOptions(app);

            timewindow = options.timewindow;
            windowIndexLength = round(diff(timewindow) * app.fs);
            
            windowStartIndex = round(timewindow(1) * app.fs);
            windowEndIndex = windowStartIndex + windowIndexLength - 1;
            windowInSeconds = [windowStartIndex, windowEndIndex] ./ app.fs;
            
            cs_durations = [];

            options = namedargs2cell(options);
            DataMatrix = zeros(windowIndexLength, numTanks);
            for i = 1 : numTanks
                tempData_ =processFibData(Data{i}, options{:});
                DataMatrix(:,i) = mean(tempData_.processedData, 2);
                cs_durations = [cs_durations; diff(tempData_.cs, [], 2)];
                
            end

            % Calculate CS and US duration
            cs_time = [0, mean(cs_durations)];
            if expType == "Conditioning"
                us_time = [cs_time(2) - app.USOffset.Value, cs_time(2)];
            else
                us_time = [0,0];
            end
            
            % Check if cs duration is different
            if (std(cs_durations) > 0.2)
                warndlg(...
                    'CS duration varies between tanks. Are you sure you are using the same experiment paradigm?',...
                    app.FibFibUIFigure.NextPlot);
            end
            
            % Set line color
            if numel(app.lineObjectForMeanGraphFunction) > 0
                LineColor = hsv(10);
                LineColor = LineColor(numel(app.lineObjectForMeanGraphFunction),:);
            else
                LineColor = [64,75,150]./255;
            end
            
            % Set Axes
            ax = targetFigureObj.CurrentAxes;
            if isempty(ax)
                ax = gca(targetFigureObj);
            end

            % Draw
            [~, l] = plotdFF(...
                linspace(windowInSeconds(1), windowInSeconds(2), windowIndexLength),...
                DataMatrix,...
                cs_time,...
                us_time,...
                ax,...
                'LineColor', LineColor);

            % Labels
            options = readProcessOptions(app);
            if options.baseline_correction == "z"
                title(ax, '\Delta F / F (z score)');
                ylabel(ax, 'Z');
            elseif options.baseline_correction == "zero"
                title(ax, '\Delta F / F (baseline to zero)');
            else
                title(ax, '\Delta F / F (%)');
            end
            xlabel(ax, 'Time (s)');
            
            
            % Make Legend
            app.lineObjectForMeanGraphFunction = [app.lineObjectForMeanGraphFunction, l];
            app.lineObjectNameForMeanGraphFunction = [...
                app.lineObjectNameForMeanGraphFunction,...
                string(app.GroupNameEditField.Value)];
            legend(app.lineObjectForMeanGraphFunction, app.lineObjectNameForMeanGraphFunction);

            
            if app.YAxisAuto.Value == false
                fig = gcf;
                for ax = fig.Children
                    ylim(ax, [app.YAxisBot.Value, app.YAxisTop.Value]);
                end
            end

        end

        % Value changed function: YAxisAuto
        function YAxisAutoValueChanged(app, event)
            if app.YAxisAuto.Value
                app.YAxisBot.Enable = "off";
                app.YAxisTop.Enable = "off";
            else
                app.YAxisBot.Enable = "on";
                app.YAxisTop.Enable = "on";
            end            
        end

        % Value changed function: SaveFigureCheckBox
        function SaveFigureCheckBoxValueChanged(app, event)
            value = app.SaveFigureCheckBox.Value;
            if value
                app.SaveFigurePath.Enable = "on";
                app.SaveFigurePathSelectButton.Enable = "on";
            else
                app.SaveFigurePath.Enable = "off";
                app.SaveFigurePathSelectButton.Enable = "off";
            end
        end

        % Button pushed function: SaveFigurePathSelectButton
        function SaveFigurePathSelectButtonPushed(app, event)
            selpath = uigetdir(app.BasePath.Value, 'Select Figure Output Path');
            if selpath ~= 0 
                app.SaveFigurePath.Value = selpath;
            end
        end

        % Value changed function: SaveFigurePath
        function SaveFigurePathValueChanged(app, event)
            value = app.SaveFigurePath.Value;
            if ~isfolder(value)
                errordlg('Not a valid figure save path!', app.FibFibUIFigure.Name);
                app.SaveFigurePath.Value = '';
            end
        end

        % Button pushed function: PrintAUCmeanFFButton
        function PrintAUCmeanFFButtonPushed(app, event)
            % check if the AUC timewindow is in the range
            if ~(app.AUCTimeWindow1.Value <= app.TimeWindow1.Value & app.AUCTimeWindow2.Value <= app.TimeWindow2.Value)
                errordlg('AUC window must be in the Timewindow''s range', app.FibFibUIFigure.Name);
                return;
            end

            tankNames = strings(numel(app.TankList.Items),1);
            AUC = zeros(numel(app.TankList.Items),1);
            meandFF = zeros(numel(app.TankList.Items),1);
            for i = 1 : numel(app.TankList.Items)
                % Parse Tank Name
                items = app.TankList.Items(i);
                result_ = regexp(cell2mat(items), '.*\\(?<tankname>.*)', 'names');
                tankNames(i) = result_.tankname;

                options = readProcessOptions(app);
                options = namedargs2cell(options);

                Data = loadFibData(cell2mat(items));

                Data = processFibData(Data, options{:});
                
                % Get target timewindow from processed data
                windowIndexLength = round((app.AUCTimeWindow2.Value - app.AUCTimeWindow1.Value) * app.fs);                
                windowStartIndex = round((app.AUCTimeWindow1.Value + app.TimeWindow1.Value) * app.fs);
                windowEndIndex = windowStartIndex + windowIndexLength - 1;

                % Mean Trial
                if app.AUCTrialRange_1.Value > Data.numTrial && app.AUCTrialRange_2.Value > Data.numTrial
                    errordlg('AUC trial range is greater than data trial', app.FibFibUIFigure.Name);
                    return;
                end
                if app.AUCTrialRange_2.Value == inf
                    app.AUCTrialRange_2.Value = Data.numTrial;
                end

                meanProcessedData = mean(Data.processedData(windowStartIndex : windowEndIndex,...
                    app.AUCTrialRange_1.Value : app.AUCTrialRange_2.Value), 2);
                
                % AUC during timewindow
                AUC(i) = sum(meanProcessedData) * (1/Data.fs);

                % dFF during timewindow
                meandFF(i) = mean(meanProcessedData);
            end

            % Show Table
            fig = uifigure();
            fig.Position = [fig.Position(1), fig.Position(2), 600,300];
            fig.Name = sprintf('Trial : %d ~ %d', app.AUCTrialRange_1.Value, app.AUCTrialRange_2.Value);
            table_data = table(tankNames, AUC, meandFF, 'VariableNames', {'Name', 'AUC', 'mean_dFF'});
            ui_table = uitable(fig, 'Data', table_data, 'Position', [20, 40, 560, 240]);
            ui_table.SelectionType = 'row';
            function tableSelected(~, ~)
                disp(1);
                copyedString = [];
                for j = ui_table.Selection
                    copyedString = [copyedString, ...
                        sprintf('%s\t%.5f\t%.5f\n', table_data.Name(j), table_data.AUC(j), table_data.mean_dFF(j))];
                end
                clipboard('copy', copyedString);
            end
            uibutton(fig, 'push', 'Text', 'Copy', 'Position', [450, 10, 130, 20], 'ButtonPushedFcn', @tableSelected);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FibFibUIFigure = uifigure('Visible', 'off');
            app.FibFibUIFigure.Name = 'FibFib';
            app.TanksPanel = uipanel(app.FibFibUIFigure);
            app.TanksPanel.Title = 'Tanks';
            app.BasePathEditFieldLabel = uilabel(app.TanksPanel);
            app.BasePathEditFieldLabel.Text = 'Base Path';
            app.BasePath = uieditfield(app.TanksPanel, 'text');
            app.BasePath.Value = 'C:\';
            app.ListBoxLabel = uilabel(app.TanksPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.TankList = uilistbox(app.TanksPanel);
            app.TankList.Items = {};
            app.TankList.Value = {};
            app.AddTankButton = uibutton(app.TanksPanel, 'push');
            app.AddTankButton.ButtonPushedFcn = createCallbackFcn(app, @AddTankButtonPushed, true);
            app.AddTankButton.Text = 'Add Tank';
            app.ClearButton = uibutton(app.TanksPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ClearAllButton = uibutton(app.TanksPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.AddTanksButton = uibutton(app.TanksPanel, 'push');
            app.AddTanksButton.ButtonPushedFcn = createCallbackFcn(app, @AddTanksButtonPushed, true);
            app.AddTanksButton.Text = 'Add Tanks';
            app.BaselineCorrectionPanel = uipanel(app.FibFibUIFigure);
            app.BaselineCorrectionPanel.Title = 'Baseline Correction';
            app.DropdownMeanMethod = uidropdown(app.BaselineCorrectionPanel);
            app.DropdownMeanMethod.Items = {'from every Timewindow.', 'at the beginning of the experiment.'};
            app.DropdownMeanMethod.ValueChangedFcn = createCallbackFcn(app, @DropdownMeanMethodValueChanged, true);
            app.DropdownMeanMethod.Value = 'from every Timewindow.';
            app.baselineusingmeanactivityLabel = uilabel(app.BaselineCorrectionPanel);
            app.baselineusingmeanactivityLabel.Text = 'baseline using mean activity';
            app.DropdownCorrectMean = uidropdown(app.BaselineCorrectionPanel);
            app.DropdownCorrectMean.Items = {'Correct', 'Do not correct'};
            app.DropdownCorrectMean.ValueChangedFcn = createCallbackFcn(app, @DropdownCorrectMeanValueChanged, true);
            app.DropdownCorrectMean.Value = 'Correct';
            app.IgnoreinitialLabel = uilabel(app.BaselineCorrectionPanel);
            app.IgnoreinitialLabel.Text = 'Ignore initial';
            app.BaselineMeanIgnore = uispinner(app.BaselineCorrectionPanel);
            app.BaselineMeanIgnore.Limits = [0 Inf];
            app.BaselineMeanIgnore.ValueChangedFcn = createCallbackFcn(app, @BaselineMeanIgnoreValueChanged, true);
            app.sanduseLabel = uilabel(app.BaselineCorrectionPanel);
            app.sanduseLabel.Text = 's and use';
            app.BaselineMeanUse = uispinner(app.BaselineCorrectionPanel);
            app.BaselineMeanUse.Limits = [0 Inf];
            app.BaselineMeanUse.ValueChangedFcn = createCallbackFcn(app, @BaselineMeanIgnoreValueChanged, true);
            app.BaselineMeanUse.Value = 1;
            app.sdataLabel = uilabel(app.BaselineCorrectionPanel);
            app.sdataLabel.Text = 's data.';
            app.sdataLabel_2 = uilabel(app.BaselineCorrectionPanel);
            app.sdataLabel_2.Text = 's data.';
            app.BaselineStdUse = uispinner(app.BaselineCorrectionPanel);
            app.BaselineStdUse.Limits = [0 Inf];
            app.BaselineStdUse.Value = 60;
            app.sanduseLabel_2 = uilabel(app.BaselineCorrectionPanel);
            app.sanduseLabel_2.Text = 's and use';
            app.BaselineStdIgnore = uispinner(app.BaselineCorrectionPanel);
            app.BaselineStdIgnore.Limits = [0 Inf];
            app.BaselineStdIgnore.Value = 60;
            app.IgnoreinitialLabel_2 = uilabel(app.BaselineCorrectionPanel);
            app.IgnoreinitialLabel_2.Text = 'Ignore initial';
            app.DropdownStdMethod = uidropdown(app.BaselineCorrectionPanel);
            app.DropdownStdMethod.Items = {'from every Timewindow.', 'at the beginning of the experiment.'};
            app.DropdownStdMethod.ValueChangedFcn = createCallbackFcn(app, @DropdownStdMethodValueChanged, true);
            app.DropdownStdMethod.Value = 'at the beginning of the experiment.';
            app.baselineusingstdactivityLabel = uilabel(app.BaselineCorrectionPanel);
            app.baselineusingstdactivityLabel.Text = 'baseline using std activity';
            app.DropdownCorrectStd = uidropdown(app.BaselineCorrectionPanel);
            app.DropdownCorrectStd.Items = {'Correct', 'Do not correct'};
            app.DropdownCorrectStd.Value = 'Correct';
            app.TimewindowPanel = uipanel(app.FibFibUIFigure);
            app.TimewindowPanel.Title = 'Timewindow';
            app.UsedataduringLabel = uilabel(app.TimewindowPanel);
            app.UsedataduringLabel.Text = 'Use data during';
            app.TimeWindow1 = uieditfield(app.TimewindowPanel, 'numeric');
            app.TimeWindow1.Limits = [0 Inf];
            app.TimeWindow1.ValueDisplayFormat = '%.1f';
            app.TimeWindow1.Value = 5;
            app.sbeforeandLabel = uilabel(app.TimewindowPanel);
            app.sbeforeandLabel.Text = 's before, and';
            app.saftertheCSLabel = uilabel(app.TimewindowPanel);
            app.saftertheCSLabel.Text = 's after the CS.';
            app.TimeWindow2 = uieditfield(app.TimewindowPanel, 'numeric');
            app.TimeWindow2.Limits = [0 Inf];
            app.TimeWindow2.ValueDisplayFormat = '%.1f';
            app.TimeWindow2.Value = 20;
            app.sbeforeCSendsLabel = uilabel(app.TimewindowPanel);
            app.sbeforeCSendsLabel.Text = 's before CS ends.';
            app.USOffset = uieditfield(app.TimewindowPanel, 'numeric');
            app.USOffset.Limits = [0 Inf];
            app.USOffset.ValueDisplayFormat = '%.1f';
            app.USOffset.Value = 2.5;
            app.DuringconditiongUSispresentedLabel = uilabel(app.TimewindowPanel);
            app.DuringconditiongUSispresentedLabel.Text = 'During conditiong, US is presented ';
            app.FilterPanel_2 = uipanel(app.FibFibUIFigure);
            app.FilterPanel_2.Title = 'Filter';
            app.FilterEnable = uidropdown(app.FilterPanel_2);
            app.FilterEnable.Items = {'Use', 'Do not use'};
            app.FilterEnable.ValueChangedFcn = createCallbackFcn(app, @FilterEnableValueChanged, true);
            app.FilterEnable.Value = 'Do not use';
            app.smovingaveragefilterLabel = uilabel(app.FilterPanel_2);
            app.smovingaveragefilterLabel.Text = 's moving average filter.';
            app.FilterStrength = uispinner(app.FilterPanel_2);
            app.FilterStrength.Limits = [0 Inf];
            app.FilterStrength.ValueChangedFcn = createCallbackFcn(app, @FilterStrengthValueChanged, true);
            app.FilterStrength.Enable = 'off';
            app.ArtifactRejectionPanel = uipanel(app.FibFibUIFigure);
            app.ArtifactRejectionPanel.Title = 'Artifact Rejection';
            app.IgnoreinitialLabel_3 = uilabel(app.ArtifactRejectionPanel);
            app.IgnoreinitialLabel_3.Text = 'Ignore initial';
            app.ArtifactRejectionSeconds = uispinner(app.ArtifactRejectionPanel);
            app.ArtifactRejectionSeconds.Limits = [0 Inf];
            app.ArtifactRejectionSeconds.Value = 30;
            app.sLabel_5 = uilabel(app.ArtifactRejectionPanel);
            app.sLabel_5.Text = 's';
            app.DetrendEnable = uidropdown(app.ArtifactRejectionPanel);
            app.DetrendEnable.Items = {'Use', 'Do not use'};
            app.DetrendEnable.Value = 'Use';
            app.polyfitdetrendLabel = uilabel(app.ArtifactRejectionPanel);
            app.polyfitdetrendLabel.Text = 'polyfit detrend.';
            app.TabGroup = uitabgroup(app.FibFibUIFigure);
            app.TabGroup.Tooltip = {''};
            app.DrawgraphsTab = uitab(app.TabGroup);
            app.DrawgraphsTab.Title = 'Draw graphs';
            app.trialspergraphExtinctionDataOnlyLabel = uilabel(app.DrawgraphsTab);
            app.trialspergraphExtinctionDataOnlyLabel.Text = 'trials per graph (Extinction Data Only)';
            app.TrialsPerGraph = uispinner(app.DrawgraphsTab);
            app.TrialsPerGraph.Limits = [0 Inf];
            app.TrialsPerGraph.Value = 6;
            app.SaveFigureCheckBox = uicheckbox(app.DrawgraphsTab);
            app.SaveFigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveFigureCheckBoxValueChanged, true);
            app.SaveFigureCheckBox.Text = 'Save Figures';
            app.SavePathEditFieldLabel = uilabel(app.DrawgraphsTab);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SaveFigurePath = uieditfield(app.DrawgraphsTab, 'text');
            app.SaveFigurePath.ValueChangedFcn = createCallbackFcn(app, @SaveFigurePathValueChanged, true);
            app.SaveFigurePath.Enable = 'off';
            app.SaveFigurePath.Value = 'C:';
            app.SaveFigurePathSelectButton = uibutton(app.DrawgraphsTab, 'push');
            app.SaveFigurePathSelectButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigurePathSelectButtonPushed, true);
            app.SaveFigurePathSelectButton.Icon = fullfile(pathToMLAPP, 'assets', 'folder.png');
            app.SaveFigurePathSelectButton.Enable = 'off';
            app.SaveFigurePathSelectButton.Text = '';
            app.DrawmeangraphTab = uitab(app.TabGroup);
            app.DrawmeangraphTab.Title = 'Draw mean graph';
            app.HoldPrevGraphCheckBox = uicheckbox(app.DrawmeangraphTab);
            app.HoldPrevGraphCheckBox.Text = 'Hold Prev Graph';
            app.GroupNameEditFieldLabel = uilabel(app.DrawmeangraphTab);
            app.GroupNameEditFieldLabel.Text = 'Group Name';
            app.GroupNameEditField = uieditfield(app.DrawmeangraphTab, 'text');
            app.PrintAUCmeanFFTab = uitab(app.TabGroup);
            app.PrintAUCmeanFFTab.Title = 'Print AUC/ mean ΔF/F';
            app.CalculateAUCAreaUnerCurveandmeanFFduringLabel = uilabel(app.PrintAUCmeanFFTab);
            app.CalculateAUCAreaUnerCurveandmeanFFduringLabel.Text = 'Calculate AUC (Area Uner Curve) and mean ΔF/F during';
            app.AUCTimeWindow1 = uieditfield(app.PrintAUCmeanFFTab, 'numeric');
            app.AUCTimeWindow1.Limits = [0 Inf];
            app.AUCTimeWindow1.ValueDisplayFormat = '%.1f';
            app.sLabel_6 = uilabel(app.PrintAUCmeanFFTab);
            app.sLabel_6.Text = 's ~';
            app.AUCTimeWindow2 = uieditfield(app.PrintAUCmeanFFTab, 'numeric');
            app.AUCTimeWindow2.Limits = [0 Inf];
            app.AUCTimeWindow2.ValueDisplayFormat = '%.1f';
            app.AUCTimeWindow2.Value = 10;
            app.sfromCSOnLabel = uilabel(app.PrintAUCmeanFFTab);
            app.sfromCSOnLabel.Text = 's from CS On';
            app.AUCTrialRange_1 = uispinner(app.PrintAUCmeanFFTab);
            app.AUCTrialRange_1.Limits = [1 Inf];
            app.AUCTrialRange_1.Value = 1;
            app.AUCTrialRange_2 = uispinner(app.PrintAUCmeanFFTab);
            app.AUCTrialRange_2.Limits = [1 Inf];
            app.AUCTrialRange_2.Value = Inf;
            app.Label = uilabel(app.PrintAUCmeanFFTab);
            app.Label.Text = '~';
            app.TrialrangeLabel = uilabel(app.PrintAUCmeanFFTab);
            app.TrialrangeLabel.Text = 'Trial range : ';
            app.FunctionsPanel = uipanel(app.FibFibUIFigure);
            app.FunctionsPanel.Title = 'Functions';
            app.PrintAUCmeanFFButton = uibutton(app.FunctionsPanel, 'push');
            app.PrintAUCmeanFFButton.ButtonPushedFcn = createCallbackFcn(app, @PrintAUCmeanFFButtonPushed, true);
            app.PrintAUCmeanFFButton.Text = 'Print AUC/ mean ΔF/F';
            app.DrawmeangraphButton = uibutton(app.FunctionsPanel, 'push');
            app.DrawmeangraphButton.ButtonPushedFcn = createCallbackFcn(app, @DrawmeangraphButtonPushed, true);
            app.DrawmeangraphButton.Text = 'Draw mean graph';
            app.DrawgraphsButton = uibutton(app.FunctionsPanel, 'push');
            app.DrawgraphsButton.ButtonPushedFcn = createCallbackFcn(app, @DrawgraphsButtonPushed, true);
            app.DrawgraphsButton.Text = 'Draw graphs';
            app.DraweachtanksdataLabel = uilabel(app.FunctionsPanel);
            app.DraweachtanksdataLabel.Text = 'Draw each tank''s data';
            app.DrawalldataintoonegraphLabel = uilabel(app.FunctionsPanel);
            app.DrawalldataintoonegraphLabel.Text = 'Draw all data into one graph';
            app.PrintAUCmeanFFtableLabel = uilabel(app.FunctionsPanel);
            app.PrintAUCmeanFFtableLabel.Text = 'Print AUC & mean ΔF/F table';
            app.Panel = uipanel(app.FibFibUIFigure);
            app.Panel.Title = 'Panel';
            app.yaxisRangeLabel = uilabel(app.Panel);
            app.yaxisRangeLabel.Text = 'yaxis Range';
            app.YAxisTop = uispinner(app.Panel);
            app.YAxisTop.Enable = 'off';
            app.YAxisTop.Value = 6;
            app.YAxisBot = uispinner(app.Panel);
            app.YAxisBot.Enable = 'off';
            app.YAxisBot.Value = -3;
            app.YAxisAuto = uicheckbox(app.Panel);
            app.YAxisAuto.ValueChangedFcn = createCallbackFcn(app, @YAxisAutoValueChanged, true);
            app.YAxisAuto.Text = 'Auto';
            app.YAxisAuto.Value = true;
            app.fromLabel = uilabel(app.Panel);
            app.fromLabel.Text = 'from';
            app.toLabel = uilabel(app.Panel);
            app.toLabel.Text = 'to';
            % Show the figure after all components are created
            app.FibFibUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FibFib

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FibFibUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.FibFibUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FibFibUIFigure)
        end
    end
end